classdef test_ui_combined_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHMGUIV22UIFigure              matlab.ui.Figure
        FileSaveNameEditFieldLabel     matlab.ui.control.Label
        FileSaveNameEditField          matlab.ui.control.EditField
        ImagingLabel                   matlab.ui.control.Label
        ProcessingLabel                matlab.ui.control.Label
        StartPreviewButton             matlab.ui.control.Button
        StartRecordButton              matlab.ui.control.Button
        StopRecordButton               matlab.ui.control.Button
        StopPreviewButton              matlab.ui.control.Button
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        RefracIndexDiffEditFieldLabel  matlab.ui.control.Label
        RefracIndexDiffEditField       matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel       matlab.ui.control.Label
        PixelSizemEditField            matlab.ui.control.NumericEditField
        ApplyChangesButton             matlab.ui.control.Button
        ExposuresEditField_2Label      matlab.ui.control.Label
        ExposuresEditField             matlab.ui.control.NumericEditField
        CameraModeDropDownLabel        matlab.ui.control.Label
        CameraModeDropDown             matlab.ui.control.DropDown
        FramerateLiveEditFieldLabel    matlab.ui.control.Label
        FramerateLiveEditField         matlab.ui.control.NumericEditField
        FramerateSaveEditFieldLabel    matlab.ui.control.Label
        FramerateSaveEditField         matlab.ui.control.NumericEditField
        ROISelectButton                matlab.ui.control.Button
        ROIResetButton                 matlab.ui.control.Button
        ShowFFTButton                  matlab.ui.control.Button
        CloseFFTButton                 matlab.ui.control.Button
        LiveReconButton                matlab.ui.control.Button
        CloseReconButton               matlab.ui.control.Button
        UpperLimitEditField_3Label     matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        InvertZaxisCheckBox            matlab.ui.control.CheckBox
        LowerLimitCheckBox             matlab.ui.control.CheckBox
        PreviewButton                  matlab.ui.control.Button
        SelectFileButton               matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        SelectROIButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ConverttoimagesButton          matlab.ui.control.Button
        FrameSkipEditFieldLabel        matlab.ui.control.Label
        FrameSkipEditField             matlab.ui.control.NumericEditField
        StartsecEditFieldLabel         matlab.ui.control.Label
        StartsecEditField              matlab.ui.control.NumericEditField
        EndsecEditFieldLabel           matlab.ui.control.Label
        EndsecEditField                matlab.ui.control.NumericEditField
        NoiseLevelEditField_3Label     matlab.ui.control.Label
        NoiseLevelEditField            matlab.ui.control.NumericEditField
        RunAutoButton                  matlab.ui.control.Button
        RunManualButton                matlab.ui.control.Button
        ImageFolderEditFieldLabel      matlab.ui.control.Label
        ImageFolderEditField           matlab.ui.control.EditField
        OutputFolderEditFieldLabel     matlab.ui.control.Label
        OutputFolderEditField          matlab.ui.control.EditField
        SelectFolderButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        file_path  % file selection
        image_status
        video_status
        image_folder_path   % image folder selection
        image_folder_status
        processing_roi
        desktop_path        % system desktop directory
        
        my_recorder
        my_camera
        my_reconstructor
        my_vid2img
        
        previewing = false;
        fft_check_control
        live_recon_control
    end
    
    methods (Access = private)
        function reset_input_selection(app)
            app.SelectFileButton.Enable = 'on';
            app.SelectFolderButton.Enable = 'on';
            app.PreviewButton.Enable = 'off';
            app.SelectROIButton.Enable = 'off';
            app.ConverttoimagesButton.Enable = 'off';
            app.FrameSkipEditField.Enable = 'off';
            app.StartsecEditField.Enable = 'off';
            app.EndsecEditField.Enable = 'off';
            app.RunAutoButton.Enable = 'off';
            app.RunManualButton.Enable = 'off';
            app.ImageFolderEditField.Enable = 'off';
            app.TextArea.Value = '';
            app.ImageFolderEditField.Value = 'image_folder';
            app.OutputFolderEditField.Value = 'output_folder';
            app.image_status = 0;
            app.video_status = 0;
            app.image_folder_status = 0;
            cla(app.UIAxes);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~, app.desktop_path] = system('echo %USERPROFILE%');
            app.desktop_path = strcat(app.desktop_path, '\Desktop\');
            app.my_recorder = recorder;
            app.my_reconstructor = reconstructor;
            app.my_vid2img = vid2img;
            
            app.StartPreviewButton.Enable = 'on';
            app.StopPreviewButton.Enable = 'off';
            app.StartRecordButton.Enable = 'off';
            app.StopRecordButton.Enable = 'off';
            app.ApplyChangesButton.Enable = 'off';
            app.ROISelectButton.Enable = 'off';
            app.ROIResetButton.Enable = 'off';
            app.ShowFFTButton.Enable = 'off';
            app.CloseFFTButton.Enable = 'off';
            app.LiveReconButton.Enable = 'off';
            app.CloseReconButton.Enable = 'off';
            
            app.SelectFileButton.Enable = 'on';
            app.SelectFolderButton.Enable = 'on';
            app.PreviewButton.Enable = 'off';
            app.SelectROIButton.Enable = 'off';
            app.ConverttoimagesButton.Enable = 'off';
            app.FrameSkipEditField.Enable = 'off';
            app.StartsecEditField.Enable = 'off';
            app.EndsecEditField.Enable = 'off';
            app.RunAutoButton.Enable = 'off';
            app.RunManualButton.Enable = 'off';
            app.ImageFolderEditField.Enable = 'off';
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
    imaqreset;
    app.my_camera = camera(app.CameraModeDropDown.Value, app.ExposuresEditField.Value); % to-do: put somewhere else
    
    preview(app.my_camera.vid);
    app.previewing = true;
    app.FramerateLiveEditField.Value = app.my_camera.src.AcquisitionResultingFrameRate;
    app.FramerateSaveEditField.Value = app.my_camera.src.AcquisitionResultingFrameRate;
    
    app.StartPreviewButton.Enable = 'off';
    app.StopPreviewButton.Enable = 'on';
    app.StartRecordButton.Enable = 'on';
    app.StopRecordButton.Enable = 'off';
    app.ApplyChangesButton.Enable = 'on';
    
    app.ROISelectButton.Enable = 'on';
    app.ROIResetButton.Enable = 'on';
    app.ShowFFTButton.Enable = 'on';
    app.CloseFFTButton.Enable = 'on';
    app.LiveReconButton.Enable = 'on';
    app.CloseReconButton.Enable = 'on';
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
    if app.previewing == true
        app.my_recorder.start(app.my_camera,app.desktop_path,app.FileSaveNameEditField.Value);
        
        app.StartRecordButton.Enable = 'off';
        app.StopRecordButton.Enable = 'on';
        app.StartPreviewButton.Enable = 'off';
        app.StopPreviewButton.Enable = 'off';
        app.ApplyChangesButton.Enable = 'off';
        
        app.ROISelectButton.Enable = 'off';
        app.ROIResetButton.Enable = 'off';
        app.ShowFFTButton.Enable = 'off';
        app.CloseFFTButton.Enable = 'off';
        app.LiveReconButton.Enable = 'off';
        app.CloseReconButton.Enable = 'off';
    end
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
    if app.previewing == true
        app.my_recorder.stop(app.my_camera);
        
        app.StartPreviewButton.Enable = 'off';
        app.StopPreviewButton.Enable = 'on';
        app.StartRecordButton.Enable = 'on';
        app.StopRecordButton.Enable = 'off';
        app.ApplyChangesButton.Enable = 'on';
        
        app.ROISelectButton.Enable = 'on';
        app.ROIResetButton.Enable = 'on';
        app.ShowFFTButton.Enable = 'on';
        app.CloseFFTButton.Enable = 'on';
        app.LiveReconButton.Enable = 'on';
        app.CloseReconButton.Enable = 'on';
    end
        end

        % Button pushed function: StopPreviewButton
        function StopPreviewButtonPushed(app, event)
    if app.previewing == true
        closepreview(app.my_camera.vid);
        app.previewing = false;
        
        app.StartPreviewButton.Enable = 'on';
        app.StopPreviewButton.Enable = 'off';
        app.StartRecordButton.Enable = 'off';
        app.StopRecordButton.Enable = 'off';
        app.ApplyChangesButton.Enable = 'off';
        
        app.ROISelectButton.Enable = 'off';
        app.ROIResetButton.Enable = 'off';
        app.ShowFFTButton.Enable = 'off';
        app.CloseFFTButton.Enable = 'off';
        app.LiveReconButton.Enable = 'off';
        app.CloseReconButton.Enable = 'off';
    end
    imaqreset;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
    if strcmp(app.my_camera.mode, app.CameraModeDropDown.Value)
        app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
    else
        StartPreviewButtonPushed(app, event);
    end
        end

        % Button pushed function: ROISelectButton
        function ROISelectButtonPushed(app, event)
    frame = getsnapshot(app.my_camera.vid);
    figure('Name', 'Preview ROI Select')
    imshow(frame, [min(frame(:)) max(frame(:))]);
    roi = drawrectangle;
    app.my_camera.vid.ROIPosition = floor(roi.Position);
    close 'Preview ROI Select';
        end

        % Button pushed function: ROIResetButton
        function ROIResetButtonPushed(app, event)
    app.my_camera.vid.ROIPosition = [384 368 1280 800];
        end

        % Button pushed function: ShowFFTButton
        function ShowFFTButtonPushed(app, event)
    app.fft_check_control = 1;
    figure('Name', 'FFT Check');
    while app.fft_check_control == 1
        img = getsnapshot(app.my_camera.vid);
        logimg = log(abs(fftshift(fft2(double(img)))));
        logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
        imagesc(logimgmaxmin);
    end
    close 'FFT Check';
        end

        % Button pushed function: CloseFFTButton
        function CloseFFTButtonPushed(app, event)
    app.fft_check_control = 0;
        end

        % Button pushed function: LiveReconButton
        function LiveReconButtonPushed(app, event)
    
    figure('Name', 'FFT Cropping for Live Reconstruction');
    img = getsnapshot(app.my_camera.vid);
    
    try
        gpuDevice;
        img = gpuArray(img);
    catch ME
        warning("GPU not enabled.");
        ME;
    end
    
    app.live_recon_control = 1;
    logimg = log(abs(fftshift(fft2(double(img)))));
    logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
    imshow(logimgmaxmin);
    roi_live = drawrectangle;
    first_order = floor(roi_live.Position);
    close 'FFT Cropping for Live Reconstruction';
    
    app.live_recon_control = 1;
    figure('Name', 'Live Reconstruction');
    while app.live_recon_control == 1
        tic
        img = getsnapshot(app.my_camera.vid);
        
        try
            img = gpuArray(img);
        catch ME
            
        end
        
        fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
        imgsize = size(fftimg); % determine image size
        
        fftimgcrop = fftimg(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)); % locate first order
        centreimg = gpuArray(zeros(imgsize(1), imgsize(2))); % create a black image for placing first order
        centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+first_order(3)) = fftimgcrop; % place first order at centre
        phase = angle(ifft2(ifftshift(centreimg))); % phase
        phase = gather(phase);
        phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
        %phase_unwrap = gather(phase_unwrap);
        %                 wavelength = app.WavelengthnmEditField.Value * 10^(-3);
        %                 refractive_index_diff = app.RefracIndexDiffEditField.Value;
        %                 factor = wavelength/(2*pi*refractive_index_diff);
        %                 thickness = (factor * phase_unwrap);
        %                 thickness(thickness < 0) = 0;
        %                 conv_mask = ones(5, 5) / 5^2;
        %                 thickness = conv2(thickness, conv_mask, 'same');
        %                 thickness = imresize(thickness, app.PixelSizemEditField.Value);
        imagesc(phase_unwrap); colorbar;
        toc
        pause(0.1); % to-do: why does this need to be here????
    end
        end

        % Button pushed function: CloseReconButton
        function CloseReconButtonPushed(app, event)
    app.live_recon_control = 0;
    close 'Live Reconstruction';
        end

        % Value changed function: ExposuresEditField
        function ExposuresEditFieldValueChanged(app, event)
    app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
        end

        % Close request function: DHMGUIV22UIFigure
        function DHMGUIV22UIFigureCloseRequest(app, event)
    if app.previewing == true
        closepreview(app.my_camera.vid);
        app.previewing = false;
    end
    delete(app)
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [name, folder] = uigetfile({'*.avi; *.tif; *.tiff'}, 'Select image or video', app.desktop_path);
            if ~isequal(name,0)
                app.file_path = fullfile(folder, name);
                if strcmp(name(end-3:end), '.avi')
                    app.TextArea.Value = ['Video selected: ' app.file_path];
                    video = VideoReader(app.file_path);
                    app.EndsecEditField.Value = video.Duration;
                    app.ImageFolderEditField.Value = [name '_image_folder'];
                    app.OutputFolderEditField.Value = [name '_output_folder'];
                    
                    app.StartsecEditField.Enable = 'on';
                    app.EndsecEditField.Enable = 'on';
                    app.ConverttoimagesButton.Enable = 'off';
                    app.FrameSkipEditField.Enable = 'on';
                    app.ImageFolderEditField.Enable = 'on';
                    app.video_status = 1;
                    app.image_status = 0;
                    app.image_folder_status = 0;
                else
                    app.TextArea.Value = ['Image selected: ' app.file_path];
                    app.StartsecEditField.Enable = 'off';
                    app.EndsecEditField.Enable = 'off';
                    app.ConverttoimagesButton.Enable = 'off';
                    app.FrameSkipEditField.Enable = 'off';
                    app.ImageFolderEditField.Enable = 'off';
                    app.ImageFolderEditField.Value = 'image_folder';
                    app.image_status = 1;
                    app.video_status = 0;
                    app.image_folder_status = 0;
                end
                app.PreviewButton.Enable = 'on';
                app.SelectROIButton.Enable = 'on';
            else
                reset_input_selection(app);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.image_folder_path = uigetdir(app.desktop_path, 'Select image folder');
            if ~isequal(app.image_folder_path,0)
                contents = dir([app.image_folder_path '\*.tif']);
                if ~isempty(contents)
                    [~,name] = fileparts(app.image_folder_path);
                    app.TextArea.Value = ['Image Folder selected: ' app.image_folder_path];
                    app.OutputFolderEditField.Value = [name '_output_folder'];
                    app.SelectROIButton.Enable = 'on';
                    app.image_status = 0;
                    app.video_status = 0;
                    app.image_folder_status = 1;
                else
                    app.TextArea.Value = ['Folder is empty, select a new folder.'];
                end
            else
                reset_input_selection(app);
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if app.image_status
                figure('Name', 'Image Preview');
                imshow(app.file_path);
            end
            if app.video_status
                implay(app.file_path);
            end
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if app.image_status
                image = imread(app.file_path);
                figure('name', 'Processing ROI Select');
                imshow(image);
                roi = drawrectangle;
                app.processing_roi = ceil(roi.Position);
                close 'Processing ROI Select';
                line_width = 10;
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + line_width) = 255; % right
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) + app.processing_roi(3) - 1 - line_width : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % left
                image(app.processing_roi(2) : app.processing_roi(2) + line_width, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % top
                image(app.processing_roi(2) + app.processing_roi(4) - 1 - line_width : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % bot
                imshow(image, 'parent', app.UIAxes);
            end
            if app.video_status
                video = VideoReader(app.file_path);
                video.CurrentTime = app.StartsecEditField.Value;
                frame = readFrame(video);
                figure('name', 'Processing ROI Select');
                imshow(frame);
                roi = drawrectangle;
                app.processing_roi = ceil(roi.Position);
                close 'Processing ROI Select';
                line_width = 10;
                frame(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + line_width) = 255; % right
                frame(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) + app.processing_roi(3) - 1 - line_width : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % left
                frame(app.processing_roi(2) : app.processing_roi(2) + line_width, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % top
                frame(app.processing_roi(2) + app.processing_roi(4) - 1 - line_width : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % bot
                imshow(frame, 'parent', app.UIAxes);
                
                app.ConverttoimagesButton.Enable = 'on';
            end
            if app.image_folder_status
                app.image_folder_path
                contents = dir([app.image_folder_path '\*.tif']);
                first_image_name = contents(1).name;
                image = imread([app.image_folder_path '\' first_image_name]);
                figure('name', 'Processing ROI Select');
                imshow(image);
                roi = drawrectangle;
                app.processing_roi = ceil(roi.Position);
                close 'Processing ROI Select';
                line_width = 10;
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + line_width) = 255; % right
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) + app.processing_roi(3) - 1 - line_width : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % left
                image(app.processing_roi(2) : app.processing_roi(2) + line_width, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % top
                image(app.processing_roi(2) + app.processing_roi(4) - 1 - line_width : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % bot
                imshow(image, 'parent', app.UIAxes);
            end
        end

        % Button pushed function: ConverttoimagesButton
        function ConverttoimagesButtonPushed(app, event)
            app.TextArea.Value = 'Converting...';
            pause(0.5);
            tic;
            [video, start_frame, end_frame] = app.my_vid2img.frame_calc(app.file_path, app.StartsecEditField.Value, app.EndsecEditField.Value);
            save_folder = app.my_vid2img.make_folder(app.desktop_path, app.ImageFolderEditField.Value);
            line_width = 5;
            app.my_vid2img.convert(save_folder, video, app.processing_roi, app.FrameSkipEditField.Value, start_frame, end_frame, line_width);
            t = toc;
            app.TextArea.Value = ['Done! Total time: ' num2str(t) 's'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DHMGUIV22UIFigure = uifigure('Visible', 'off');
            app.DHMGUIV22UIFigure.Name = 'DHM GUI V2.2';
            app.DHMGUIV22UIFigure.CloseRequestFcn = createCallbackFcn(app, @DHMGUIV22UIFigureCloseRequest, true);
            app.FileSaveNameEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FileSaveNameEditFieldLabel.Text = 'File Save Name';
            app.FileSaveNameEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.FileSaveNameEditField.Value = 'video.avi';
            app.ImagingLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ImagingLabel.Text = 'Imaging';
            app.ProcessingLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.StartPreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.StartRecordButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.StopPreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StopPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StopPreviewButtonPushed, true);
            app.StopPreviewButton.Text = 'Stop Preview';
            app.WavelengthnmEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.WavelengthnmEditField.Limits = [1 Inf];
            app.WavelengthnmEditField.Value = 514;
            app.RefracIndexDiffEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.RefracIndexDiffEditFieldLabel.Text = 'Refrac. Index Diff.';
            app.RefracIndexDiffEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.RefracIndexDiffEditField.Limits = [0 1];
            app.RefracIndexDiffEditField.Value = 0.06;
            app.PixelSizemEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (μm)';
            app.PixelSizemEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.PixelSizemEditField.Limits = [0 Inf];
            app.PixelSizemEditField.Value = 0.5;
            app.ApplyChangesButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ExposuresEditField_2Label = uilabel(app.DHMGUIV22UIFigure);
            app.ExposuresEditField_2Label.Text = 'Exposure (μs)';
            app.ExposuresEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.ExposuresEditField.Limits = [12 15003];
            app.ExposuresEditField.ValueDisplayFormat = '%.0f';
            app.ExposuresEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuresEditFieldValueChanged, true);
            app.ExposuresEditField.Value = 500;
            app.CameraModeDropDownLabel = uilabel(app.DHMGUIV22UIFigure);
            app.CameraModeDropDownLabel.Text = 'Camera Mode';
            app.CameraModeDropDown = uidropdown(app.DHMGUIV22UIFigure);
            app.CameraModeDropDown.Items = {'Mono16', 'Mono8', 'Mono10Packed', 'Mono12Packed'};
            app.CameraModeDropDown.Value = 'Mono16';
            app.FramerateLiveEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FramerateLiveEditFieldLabel.Text = 'Framerate (Live)';
            app.FramerateLiveEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FramerateLiveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateLiveEditField.Editable = 'off';
            app.FramerateSaveEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FramerateSaveEditFieldLabel.Text = 'Framerate (Save)';
            app.FramerateSaveEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FramerateSaveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateSaveEditField.Editable = 'off';
            app.ROISelectButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ROISelectButton.ButtonPushedFcn = createCallbackFcn(app, @ROISelectButtonPushed, true);
            app.ROISelectButton.Text = 'ROI Select';
            app.ROIResetButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ROIResetButton.ButtonPushedFcn = createCallbackFcn(app, @ROIResetButtonPushed, true);
            app.ROIResetButton.Text = 'ROI Reset';
            app.ShowFFTButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ShowFFTButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFFTButtonPushed, true);
            app.ShowFFTButton.Text = 'Show FFT';
            app.CloseFFTButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.CloseFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFFTButtonPushed, true);
            app.CloseFFTButton.Text = 'Close FFT';
            app.LiveReconButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.LiveReconButton.ButtonPushedFcn = createCallbackFcn(app, @LiveReconButtonPushed, true);
            app.LiveReconButton.Text = 'Live Recon';
            app.CloseReconButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.CloseReconButton.ButtonPushedFcn = createCallbackFcn(app, @CloseReconButtonPushed, true);
            app.CloseReconButton.Text = 'Close Recon';
            app.UpperLimitEditField_3Label = uilabel(app.DHMGUIV22UIFigure);
            app.UpperLimitEditField_3Label.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Value = 20;
            app.InvertZaxisCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.InvertZaxisCheckBox.Text = 'Invert Z-axis';
            app.LowerLimitCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.LowerLimitCheckBox.Text = 'Lower Limit';
            app.LowerLimitCheckBox.Value = true;
            app.PreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SelectFileButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.TextArea = uitextarea(app.DHMGUIV22UIFigure);
            app.TextArea.Editable = 'off';
            app.SelectROIButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.UIAxes = uiaxes(app.DHMGUIV22UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ConverttoimagesButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ConverttoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoimagesButtonPushed, true);
            app.ConverttoimagesButton.Text = 'Convert to images';
            app.FrameSkipEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FrameSkipEditField.Limits = [1 Inf];
            app.FrameSkipEditField.Value = 1;
            app.StartsecEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.StartsecEditFieldLabel.Text = 'Start (sec)';
            app.StartsecEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.StartsecEditField.Limits = [0 Inf];
            app.EndsecEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.EndsecEditFieldLabel.Text = 'End (sec)';
            app.EndsecEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.EndsecEditField.Limits = [0 Inf];
            app.NoiseLevelEditField_3Label = uilabel(app.DHMGUIV22UIFigure);
            app.NoiseLevelEditField_3Label.Text = 'Noise Level';
            app.NoiseLevelEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.NoiseLevelEditField.Limits = [0 100];
            app.NoiseLevelEditField.Value = 30;
            app.RunAutoButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunAutoButton.Text = 'Run Auto';
            app.RunManualButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunManualButton.Text = 'Run Manual';
            app.ImageFolderEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ImageFolderEditFieldLabel.Text = 'Image Folder';
            app.ImageFolderEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.ImageFolderEditField.Value = 'image_folder';
            app.OutputFolderEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.OutputFolderEditField.Value = 'output_folder';
            app.SelectFolderButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.DHMGUIV22UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_ui_combined_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHMGUIV22UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHMGUIV22UIFigure)
        end
    end
end