classdef test_ui_new_naming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHMGUIV22UIFigure               matlab.ui.Figure
        NoiseLevelEditFieldLabel        matlab.ui.control.Label
        NoiseLevelEditField             matlab.ui.control.NumericEditField
        RunAutoButton                   matlab.ui.control.Button
        RunManualButton                 matlab.ui.control.Button
        ManualFrequencyCroppingLabel    matlab.ui.control.Label
        AutoFrequencyCroppingLabel      matlab.ui.control.Label
        FileSaveNameEditFieldLabel      matlab.ui.control.Label
        FileSaveNameEditField           matlab.ui.control.EditField
        ImagingLabel                    matlab.ui.control.Label
        SingleFrameReconstructionLabel  matlab.ui.control.Label
        ROISelectionLabel               matlab.ui.control.Label
        ConvertButton                   matlab.ui.control.Button
        FrameIntevalEditFieldLabel      matlab.ui.control.Label
        FrameIntevalEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        RunBulkAutoButton               matlab.ui.control.Button
        BatchFrameReconstructionLabel   matlab.ui.control.Label
        StartPreviewButton              matlab.ui.control.Button
        StartRecordButton               matlab.ui.control.Button
        StopRecordButton                matlab.ui.control.Button
        StopPreviewButton               matlab.ui.control.Button
        NoiseLevelEditField_2Label      matlab.ui.control.Label
        NoiseLevelEditField_2           matlab.ui.control.NumericEditField
        RunCombineButton                matlab.ui.control.Button
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        SaveFolderEditField_2Label      matlab.ui.control.Label
        SaveFolderEditField_2           matlab.ui.control.EditField
        CombinedSaveFolderEditFieldLabel  matlab.ui.control.Label
        CombinedSaveFolderEditField     matlab.ui.control.EditField
        UIAxes1                         matlab.ui.control.UIAxes
        PreviewButton                   matlab.ui.control.Button
        SelectImageButton               matlab.ui.control.Button
        ImagePathTextArea               matlab.ui.control.TextArea
        SelectVideoButton               matlab.ui.control.Button
        ImagePathTextArea_2             matlab.ui.control.TextArea
        ImageFolderButton               matlab.ui.control.Button
        ImagePathTextArea_3             matlab.ui.control.TextArea
        CommonDirectoryButton           matlab.ui.control.Button
        ImagePathTextArea_4             matlab.ui.control.TextArea
        SelectROIButton                 matlab.ui.control.Button
        CalculationParametersLabel      matlab.ui.control.Label
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        RefracIndexDiffEditFieldLabel   matlab.ui.control.Label
        RefracIndexDiffEditField        matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        InvertZaxisCheckBox             matlab.ui.control.CheckBox
        SaveHeightCheckBox              matlab.ui.control.CheckBox
        SaveVolumeCheckBox              matlab.ui.control.CheckBox
        LowerLimitCheckBox              matlab.ui.control.CheckBox
        InvertZaxisCheckBox_2           matlab.ui.control.CheckBox
        LowerLimitCheckBox_2            matlab.ui.control.CheckBox
        UpperLimitEditField_2Label      matlab.ui.control.Label
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel        matlab.ui.control.Label
        PixelSizemEditField             matlab.ui.control.NumericEditField
        ApplyChangesButton              matlab.ui.control.Button
        ExposuresEditField_2Label       matlab.ui.control.Label
        ExposuresEditField              matlab.ui.control.NumericEditField
        CameraModeDropDownLabel         matlab.ui.control.Label
        CameraModeDropDown              matlab.ui.control.DropDown
        FramerateLiveEditFieldLabel     matlab.ui.control.Label
        FramerateLiveEditField          matlab.ui.control.NumericEditField
        FramerateSaveEditFieldLabel     matlab.ui.control.Label
        FramerateSaveEditField          matlab.ui.control.NumericEditField
        ROISelectButton                 matlab.ui.control.Button
        ROIResetButton                  matlab.ui.control.Button
        ShowFFTButton                   matlab.ui.control.Button
        CloseFFTButton                  matlab.ui.control.Button
        LiveReconButton                 matlab.ui.control.Button
        CloseReconButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        RunBulkManualButton             matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image_path  % single image selection
        video_path  % video selection
        processing_roi
        image_folder_path   % image folder selection
        common_directory    % common directory for figure combine
        desktop_path        % system desktop directory
    end
    
    properties
        my_recorder
        my_camera
        my_reconstructor
        my_vid2img
        
        previewing = false;
        fft_check_control
        live_recon_control
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~, app.desktop_path] = system('echo %USERPROFILE%');
            app.desktop_path = strcat(app.desktop_path, '\Desktop\');
            app.my_recorder = recorder;
            app.my_reconstructor = reconstructor;
            app.my_vid2img = vid2img;
            
            app.StartPreviewButton.Enable = 'on';
            app.StopPreviewButton.Enable = 'off';
            app.StartRecordButton.Enable = 'off';
            app.StopRecordButton.Enable = 'off';
            app.ApplyChangesButton.Enable = 'off';
            
            app.ROISelectButton.Enable = 'off';
            app.ROIResetButton.Enable = 'off';
            app.ShowFFTButton.Enable = 'off';
            app.CloseFFTButton.Enable = 'off';
            app.LiveReconButton.Enable = 'off';
            app.CloseReconButton.Enable = 'off';
            
            app.ConvertButton.Enable = 'off';
            app.SelectROIButton.Enable = 'off';
        end

        % Button pushed function: RunManualButton
        function RunManualButtonPushed(app, event)
            tic;
            
            batch_process = 0;
            first_order = [0 0 0 0];
            [imgo, preview, fftimg] = app.my_reconstructor.load_img(app.image_path, 1, 0);
            [centreimg,first_order] = app.my_reconstructor.manual_crop(batch_process,preview,fftimg, first_order);
            
            [intensity_no_curve, phase_unwrap_no_curve, thickness, lower_limit, upper_limit, ~, ~] = app.my_reconstructor.process(batch_process, centreimg, app.UpperLimitEditField_2.Value, ...
                app.LowerLimitCheckBox.Value, app.InvertZaxisCheckBox_2.Value, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value);
            
            % highlight spatial region
            show_crop_region = preview;
            show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) = ...
                show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) + 0.2;
            
            figure('name', 'Manual Cropping');
            subplot(3, 2, 1), imagesc(imgo); title('Hologram');
            subplot(3, 2, 2), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
            subplot(3, 2, 3), imagesc(intensity_no_curve); title('Intensity'); colorbar;
            subplot(3, 2, 4), imagesc(phase_unwrap_no_curve); title('Phase'); colorbar;
            subplot(3, 2, 5), imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
            subplot(3, 2, 6), mesh(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
            t = toc;
            
            app.ImagePathTextArea.Value = ['Done! Manual cropping performed.' newline 'Total time: ' num2str(t) 's'];
            
        end

        % Button pushed function: RunAutoButton
        function RunAutoButtonPushed(app, event)
            tic;
            
            [imgo, logimgmaxmin, fftimg] = app.my_reconstructor.load_img(app.image_path, 1, 0);
            
            [centreimg,region_box] = app.my_reconstructor.auto_crop(fftimg,logimgmaxmin,app.NoiseLevelEditField.Value);
            
            batch_process = 0;
            [intensity_no_curve, phase_unwrap_no_curve, thickness, lower_limit, upper_limit, ~, ~] = app.my_reconstructor.process(batch_process, centreimg, app.UpperLimitEditField_2.Value, ...
                app.LowerLimitCheckBox.Value, app.InvertZaxisCheckBox_2.Value, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value);
            
            dimmed_fftimg = logimgmaxmin;
            dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3))) = ...
                dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3))) + 0.2;
            
            t = toc;
            app.ImagePathTextArea.Value = ['Done! Auto cropping performed.' newline 'Total time: ' num2str(t) 's'];
            
            % display intensity, phase image and spatial frequency, no unwrapping
            %             figure('name', 'Auto Cropping');
            %             subplot(3, 3, 1), imagesc(imgo); title('Hologram');
            %             subplot(3, 3, 2), imagesc(intensity); title('Intensity'); colorbar;
            %             subplot(3, 3, 3), imagesc(intensity_no_curve); title('Intensity Curve Removed'); colorbar;
            %             subplot(3, 3, 4), imagesc(phase); title('Phase'); colorbar;
            %             subplot(3, 3, 5), imagesc(phase_unwrap); title('Phase Unwrapped'); colorbar;
            %             subplot(3, 3, 6), imagesc(phase_unwrap_no_curve); title('Phase Unwrapped Curve Removed'); colorbar;
            %             subplot(3, 3, 7), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
            %             subplot(3, 3, 8), imagesc(thickness); title('Thickness'); colorbar;
            %             subplot(3, 3, 9), mesh(thickness); view(-100,50); title('Thickness'); colorbar;
            figure('name', 'Auto Cropping');
            subplot(3, 2, 1); imagesc(imgo); title('Hologram');
            subplot(3, 2, 2); imagesc(dimmed_fftimg); title('Spatial Frequency Cropping'); colorbar;
            subplot(3, 2, 3); imagesc(intensity_no_curve); title('Intensity'); colorbar;
            subplot(3, 2, 4); imagesc(phase_unwrap_no_curve); title('Phase'); colorbar;
            subplot(3, 2, 5); imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
            subplot(3, 2, 6); mesh(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            imaqreset;
            app.my_camera = camera(app.CameraModeDropDown.Value, app.ExposuresEditField.Value); % to-do: put somewhere else
            
            preview(app.my_camera.vid);
            app.previewing = true;
            app.FramerateLiveEditField.Value = app.my_camera.src.AcquisitionResultingFrameRate;
            app.FramerateSaveEditField.Value = app.my_camera.src.AcquisitionResultingFrameRate;
            
            app.StartPreviewButton.Enable = 'off';
            app.StopPreviewButton.Enable = 'on';
            app.StartRecordButton.Enable = 'on';
            app.StopRecordButton.Enable = 'off';
            app.ApplyChangesButton.Enable = 'on';
            
            app.ROISelectButton.Enable = 'on';
            app.ROIResetButton.Enable = 'on';
            app.ShowFFTButton.Enable = 'on';
            app.CloseFFTButton.Enable = 'on';
            app.LiveReconButton.Enable = 'on';
            app.CloseReconButton.Enable = 'on';
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            if app.previewing == true
                app.my_recorder.start(app.my_camera,app.desktop_path,app.FileSaveNameEditField.Value);
                
                app.StartRecordButton.Enable = 'off';
                app.StopRecordButton.Enable = 'on';
                app.StartPreviewButton.Enable = 'off';
                app.StopPreviewButton.Enable = 'off';
                app.ApplyChangesButton.Enable = 'off';
                
                app.ROISelectButton.Enable = 'off';
                app.ROIResetButton.Enable = 'off';
                app.ShowFFTButton.Enable = 'off';
                app.CloseFFTButton.Enable = 'off';
                app.LiveReconButton.Enable = 'off';
                app.CloseReconButton.Enable = 'off';
            end
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            if app.previewing == true
                app.my_recorder.stop(app.my_camera);
                
                app.StartPreviewButton.Enable = 'off';
                app.StopPreviewButton.Enable = 'on';
                app.StartRecordButton.Enable = 'on';
                app.StopRecordButton.Enable = 'off';
                app.ApplyChangesButton.Enable = 'on';
                
                app.ROISelectButton.Enable = 'on';
                app.ROIResetButton.Enable = 'on';
                app.ShowFFTButton.Enable = 'on';
                app.CloseFFTButton.Enable = 'on';
                app.LiveReconButton.Enable = 'on';
                app.CloseReconButton.Enable = 'on';
            end
        end

        % Button pushed function: StopPreviewButton
        function StopPreviewButtonPushed(app, event)
            if app.previewing == true
                closepreview(app.my_camera.vid);
                app.previewing = false;
                
                app.StartPreviewButton.Enable = 'on';
                app.StopPreviewButton.Enable = 'off';
                app.StartRecordButton.Enable = 'off';
                app.StopRecordButton.Enable = 'off';
                app.ApplyChangesButton.Enable = 'off';
                
                app.ROISelectButton.Enable = 'off';
                app.ROIResetButton.Enable = 'off';
                app.ShowFFTButton.Enable = 'off';
                app.CloseFFTButton.Enable = 'off';
                app.LiveReconButton.Enable = 'off';
                app.CloseReconButton.Enable = 'off';
            end
            imaqreset;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            tic;
            [video, start_frame, end_frame] = app.my_vid2img.frame_calc(app.video_path, app.StartTimesecEditField.Value, app.EndTimesecEditField.Value);
            save_folder = app.my_vid2img.make_folder(app.desktop_path, app.SaveFolderEditField.Value);
            line_width = 2;
            app.my_vid2img.convert(save_folder, video, app.processing_roi, app.FrameIntevalEditField.Value, start_frame, end_frame, line_width);
            t = toc;
            app.ImagePathTextArea_2.Value = ['Done! Total time: ' num2str(t) 's'];
        end

        % Button pushed function: RunBulkAutoButton
        function RunBulkAutoButtonPushed(app, event)
            tic;
            
            [up, save_folder, input] = app.my_reconstructor.batch_make_folder(app.desktop_path, app.image_folder_path, ...
                app.SaveFolderEditField_2.Value, app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value);
            
            % peak height and volume for frames
            [peak_height, volume, dim] = app.my_reconstructor.height_volume_data(up);
            
            count = 1;
            
            for index = 1 : length(up)
                close all;
                file = up(index).name;
                file_dir = strcat(input, '\', file);
                
                [~, logimgmaxmin, fftimg] = app.my_reconstructor.load_img(file_dir, 1, 0);
                [centreimg,region_box] = app.my_reconstructor.auto_crop(fftimg, logimgmaxmin,app.NoiseLevelEditField_2.Value);
                
                batch_process = 1;
                [intensity_no_curve, ~, thickness, lower_limit, upper_limit, frame_peak_height, frame_volume] = app.my_reconstructor.process(batch_process, centreimg, app.UpperLimitEditField.Value, ...
                    app.LowerLimitCheckBox.Value, app.InvertZaxisCheckBox.Value, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value);
                
                % hightlight spatial region
                dimmed_fftimg = logimgmaxmin;
                dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3))) = ...
                    dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3))) + 0.2;
                imshow(dimmed_fftimg, 'parent', app.UIAxes3);
                
                % save intensity and thickness images
                f = figure('visible','off');
                imagesc(intensity_no_curve); title('Intensity'); colorbar;
                saveas(gca, [save_folder '\intensity\' 'intensity_' file]); caxis([-10, 10]);
                imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                saveas(gca, [save_folder '\thickness\' 'thickness_' file]);
                imagesc(dimmed_fftimg); title('Spatial Frequency'); colorbar;
                saveas(gca, [save_folder '\fft\' 'fft_' file]);
                mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                annotation('textbox', dim, 'String', text, 'FitBoxToText', 'on');
                saveas(gca, [save_folder '\mesh\' 'mesh_' file]);
                
                % save .csv for thickness
                [peak_height, volume] = app.my_reconstructor.single_frame_data(file, app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value, save_folder, ...
                    count, frame_peak_height, peak_height, frame_volume, volume, thickness);
                
                app.ImagePathTextArea_3.Value = ['(Auto) Currently processing image: ' file newline num2str(length(up) - count) ' images to go...'];
                
                count = count + 1;
            end
            
            app.my_reconstructor.all_frame_data(app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value, peak_height, volume, save_folder);
            
            t = toc;
            
            destination = [save_folder '\Hologram'];
            copyfile(input, destination);
            
            app.ImagePathTextArea_3.Value = ['Done! Total time: ' num2str(t) 's'];
        end

        % Button pushed function: RunCombineButton
        function RunCombineButtonPushed(app, event)
            % check_status_box = 1;
            % [~] = app.my_figure_combine.process(app.common_directory, app.desktop_path, app.CombinedSaveFolderEditField.Value, check_status_box, app.ImagePathTextArea_4.Value);
            
            tic;
            
            common_dir = app.common_directory;
            
            save_folder = strcat(app.desktop_path, app.CombinedSaveFolderEditField.Value);
            mkdir(save_folder);
            
            original_input = strcat(common_dir, '\Hologram');
            fft_input = strcat(common_dir, '\fft');
            intensity_input = strcat(common_dir, '\intensity');
            thickness_input = strcat(common_dir, '\mesh');
            
            original_up = dir(strcat(original_input, '\*.tif'));
            fft_up = dir(strcat(fft_input, '\*.tif'));
            intensity_up = dir(strcat(intensity_input, '\*.tif'));
            thickness_up = dir(strcat(thickness_input, '\*.tif'));
            
            if length(original_up) == length(fft_up) && length(original_up) == length(intensity_up) && ...
                    length(original_up) == length(thickness_up) && length(fft_up) == length(intensity_up) && ...
                    length(fft_up) == length(thickness_up) && length(intensity_up) == length(thickness_up)
                up = length(original_up);
                
                count = 1;
                
                for index = 1 : up
                    close all;
                    original = original_up(index).name;
                    fft = fft_up(index).name;
                    intensity = intensity_up(index).name;
                    thickness = thickness_up(index).name;
                    
                    original_dir = strcat(original_input, '\', original);
                    fft_dir = strcat(fft_input, '\', fft);
                    intensity_dir = strcat(intensity_input, '\', intensity);
                    thickness_dir = strcat(thickness_input, '\', thickness);
                    
                    original_img = imread(original_dir);
                    fft_img = imread(fft_dir);
                    intensity_img = imread(intensity_dir);
                    thickness_img = imread(thickness_dir);
                    
                    combine = imtile({original_img, fft_img, intensity_img, thickness_img}, 'BackgroundColor', 'white', 'GridSize', [2 2]);
                    f = figure('visible','off');
                    original = erase(original, 'cropped_');
                    imshow(combine); title(['Frame ' erase(original, '.tif')])
                    saveas(gcf, [save_folder '\' 'combined_' original])
                    
                    app.ImagePathTextArea_4.Value = ['Currently processing: ' erase(original, 'cropped_') newline num2str(up - count) ' images to go...'];
                    count = count + 1;
                end
                
                t = toc;
                app.ImagePathTextArea_4.Value = ['Done! Total time: ' num2str(t) 's'];
            else
                app.ImagePathTextArea_4.Value = ["Something is wrong. Did you copy and paste 'original' folder?" newline 'Or, check if numbers of figures across folders are equal.'];
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            imgo_preview = imread(app.image_path); % open hologram
            imshow(imgo_preview, 'parent', app.UIAxes1);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [name, folder] = uigetfile('C:\*.tif');
            if ~isequal(name,0)
                app.image_path = fullfile(folder, name);
                app.ImagePathTextArea.Value = app.image_path;
            end
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            [name, folder] = uigetfile('C:\*.avi');
            if ~isequal(name,0)
                app.video_path = fullfile(folder, name);
                app.ImagePathTextArea_2.Value = app.video_path;
                video = VideoReader(app.video_path);
                app.EndTimesecEditField.Value = video.Duration;
                app.SaveFolderEditField.Value = [name '_image_folder'];
                
                app.SelectROIButton.Enable = 'on';   
            end
        end

        % Button pushed function: ImageFolderButton
        function ImageFolderButtonPushed(app, event)
            app.image_folder_path = uigetdir;
            if ~isequal(app.image_folder_path,0)
                app.ImagePathTextArea_3.Value = app.image_folder_path;
            end
        end

        % Button pushed function: CommonDirectoryButton
        function CommonDirectoryButtonPushed(app, event)
            app.common_directory = uigetdir;
            app.ImagePathTextArea_4.Value = app.common_directory;
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            video = VideoReader(app.video_path);
            video.CurrentTime = app.StartTimesecEditField.Value;
            frame = readFrame(video);
            figure('name', 'Processing ROI Select');
            imshow(frame);
            roi = drawrectangle;
            app.processing_roi = ceil(roi.Position);
            close 'Processing ROI Select';
            frame = imcrop(frame, [app.processing_roi(1) app.processing_roi(2) app.processing_roi(3) app.processing_roi(4)]);
            imshow(frame, 'parent', app.UIAxes2);
            
            app.ConvertButton.Enable = 'on';
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            if strcmp(app.my_camera.mode, app.CameraModeDropDown.Value)
                app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
            else
                StartPreviewButtonPushed(app, event);
            end
        end

        % Button pushed function: ROISelectButton
        function ROISelectButtonPushed(app, event)
            frame = getsnapshot(app.my_camera.vid);
            figure('Name', 'Preview ROI Select')
            imshow(frame, [min(frame(:)) max(frame(:))]);
            roi = drawrectangle;
            app.my_camera.vid.ROIPosition = floor(roi.Position);
            close 'Preview ROI Select';
        end

        % Button pushed function: ROIResetButton
        function ROIResetButtonPushed(app, event)
            app.my_camera.vid.ROIPosition = [384 368 1280 800];
        end

        % Button pushed function: ShowFFTButton
        function ShowFFTButtonPushed(app, event)
            app.fft_check_control = 1;
            figure('Name', 'FFT Check');
            while app.fft_check_control == 1
                img = getsnapshot(app.my_camera.vid);
                logimg = log(abs(fftshift(fft2(double(img)))));
                logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
                imagesc(logimgmaxmin);
            end
            close 'FFT Check';
        end

        % Button pushed function: CloseFFTButton
        function CloseFFTButtonPushed(app, event)
            app.fft_check_control = 0;
        end

        % Button pushed function: LiveReconButton
        function LiveReconButtonPushed(app, event)

            figure('Name', 'FFT Cropping for Live Reconstruction');
            img = getsnapshot(app.my_camera.vid);
            
            try
                gpuDevice;
                img = gpuArray(img);
            catch ME
                warning("GPU not enabled.");
                ME;
            end
            
            app.live_recon_control = 1;
            logimg = log(abs(fftshift(fft2(double(img)))));
            logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
            imshow(logimgmaxmin);
            roi_live = drawrectangle;
            first_order = floor(roi_live.Position);
            close 'FFT Cropping for Live Reconstruction';
            
            app.live_recon_control = 1;
            figure('Name', 'Live Reconstruction');
            while app.live_recon_control == 1
                tic
                img = getsnapshot(app.my_camera.vid);
                
                try
                    img = gpuArray(img);
                catch ME
                    
                end
                
                fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
                imgsize = size(fftimg); % determine image size
                
                fftimgcrop = fftimg(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)); % locate first order
                centreimg = gpuArray(zeros(imgsize(1), imgsize(2))); % create a black image for placing first order
                centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+first_order(3)) = fftimgcrop; % place first order at centre
                phase = angle(ifft2(ifftshift(centreimg))); % phase
                phase = gather(phase);
                phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
                %phase_unwrap = gather(phase_unwrap);
                %                 wavelength = app.WavelengthnmEditField.Value * 10^(-3);
                %                 refractive_index_diff = app.RefracIndexDiffEditField.Value;
                %                 factor = wavelength/(2*pi*refractive_index_diff);
                %                 thickness = (factor * phase_unwrap);
                %                 thickness(thickness < 0) = 0;
                %                 conv_mask = ones(5, 5) / 5^2;
                %                 thickness = conv2(thickness, conv_mask, 'same');
                %                 thickness = imresize(thickness, app.PixelSizemEditField.Value);
                imagesc(phase_unwrap); colorbar;
                toc
                pause(0.1); % to-do: why does this need to be here????
            end
        end

        % Button pushed function: CloseReconButton
        function CloseReconButtonPushed(app, event)
            app.live_recon_control = 0;
            close 'Live Reconstruction';
        end

        % Button pushed function: RunBulkManualButton
        function RunBulkManualButtonPushed(app, event)
            tic;
            
            [up, save_folder, input] = app.my_reconstructor.batch_make_folder(app.desktop_path, app.image_folder_path, ...
                app.SaveFolderEditField_2.Value, app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value);
            
            % peak height and volume for frames
            [peak_height, volume, dim] = app.my_reconstructor.height_volume_data(up);
            
            count = 1;
            first_order = [0 0 0 0];
            
            for index = 1 : length(up)
                close all;
                file = up(index).name;
                file_dir = strcat(input, '\', file);
                
                [~, logimgmaxmin, fftimg] = app.my_reconstructor.load_img(file_dir, 1, 0);
                [centreimg, first_order] = app.my_reconstructor.manual_crop(index, logimgmaxmin, fftimg, first_order);
                
                batch_process = 1;
                [intensity_no_curve, ~, thickness, lower_limit, upper_limit, frame_peak_height, frame_volume] = app.my_reconstructor.process(batch_process, centreimg, app.UpperLimitEditField.Value, ...
                    app.LowerLimitCheckBox.Value, app.InvertZaxisCheckBox.Value, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value);
                
                % hightlight spatial region
                show_crop_region = logimgmaxmin;
                show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) = ...
                    show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) + 0.2;
                imshow(show_crop_region, 'parent', app.UIAxes3);
                
                % save intensity and thickness images
                f = figure('visible','off');
                imagesc(intensity_no_curve); title('Intensity'); colorbar;
                saveas(gca, [save_folder '\intensity\' 'intensity_' file]); caxis([-10, 10]);
                imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                saveas(gca, [save_folder '\thickness\' 'thickness_' file]);
                imagesc(show_crop_region); title('Spatial Frequency'); colorbar;
                saveas(gca, [save_folder '\fft\' 'fft_' file]);
                mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                annotation('textbox', dim, 'String', text, 'FitBoxToText', 'on');
                saveas(gca, [save_folder '\mesh\' 'mesh_' file]);
                
                % save .csv for thickness
                [peak_height, volume] = app.my_reconstructor.single_frame_data(file, app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value, save_folder, ...
                    count, frame_peak_height, peak_height, frame_volume, volume, thickness);
                
                app.ImagePathTextArea_3.Value = ['(Manual) Currently processing image: ' file newline num2str(length(up) - count) ' images to go...'];
                
                count = count + 1;
            end
            
            app.my_reconstructor.all_frame_data(app.SaveHeightCheckBox.Value, app.SaveVolumeCheckBox.Value, peak_height, volume, save_folder);
            
            t = toc;
            
            destination = [save_folder '\Hologram'];
            copyfile(input, destination);
            
            app.ImagePathTextArea_3.Value = ['Done! Total time: ' num2str(t) 's'];
        end

        % Value changed function: ExposuresEditField
        function ExposuresEditFieldValueChanged(app, event)
            app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
        end

        % Close request function: DHMGUIV22UIFigure
        function DHMGUIV22UIFigureCloseRequest(app, event)
            if app.previewing == true
                closepreview(app.my_camera.vid);
                app.previewing = false;
            end
        
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DHMGUIV22UIFigure = uifigure('Visible', 'off');
            app.DHMGUIV22UIFigure.Name = 'DHM GUI V2.2';
            app.DHMGUIV22UIFigure.CloseRequestFcn = createCallbackFcn(app, @DHMGUIV22UIFigureCloseRequest, true);
            app.NoiseLevelEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.NoiseLevelEditFieldLabel.Text = 'Noise Level';
            app.NoiseLevelEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.NoiseLevelEditField.Limits = [0 100];
            app.NoiseLevelEditField.Value = 30;
            app.RunAutoButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunAutoButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoButtonPushed, true);
            app.RunAutoButton.Text = 'Run Auto';
            app.RunManualButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunManualButton.ButtonPushedFcn = createCallbackFcn(app, @RunManualButtonPushed, true);
            app.RunManualButton.Text = 'Run Manual';
            app.ManualFrequencyCroppingLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ManualFrequencyCroppingLabel.Text = 'Manual Frequency Cropping';
            app.AutoFrequencyCroppingLabel = uilabel(app.DHMGUIV22UIFigure);
            app.AutoFrequencyCroppingLabel.Text = 'Auto Frequency Cropping';
            app.FileSaveNameEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FileSaveNameEditFieldLabel.Text = 'File Save Name';
            app.FileSaveNameEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.FileSaveNameEditField.Value = 'video.avi';
            app.ImagingLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ImagingLabel.Text = 'Imaging';
            app.SingleFrameReconstructionLabel = uilabel(app.DHMGUIV22UIFigure);
            app.SingleFrameReconstructionLabel.Text = 'Single Frame Reconstruction';
            app.ROISelectionLabel = uilabel(app.DHMGUIV22UIFigure);
            app.ROISelectionLabel.Text = 'ROI Selection';
            app.ConvertButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.FrameIntevalEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FrameIntevalEditFieldLabel.Text = 'Frame Inteval';
            app.FrameIntevalEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FrameIntevalEditField.Limits = [1 Inf];
            app.FrameIntevalEditField.Value = 1;
            app.StartTimesecEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.StartTimesecEditField.Limits = [0 Inf];
            app.EndTimesecEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.EndTimesecEditField.Limits = [0 Inf];
            app.RunBulkAutoButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunBulkAutoButton.ButtonPushedFcn = createCallbackFcn(app, @RunBulkAutoButtonPushed, true);
            app.RunBulkAutoButton.Text = 'Run Bulk (Auto)';
            app.BatchFrameReconstructionLabel = uilabel(app.DHMGUIV22UIFigure);
            app.BatchFrameReconstructionLabel.Text = 'Batch Frame Reconstruction';
            app.StartPreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.StartRecordButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.StopPreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.StopPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StopPreviewButtonPushed, true);
            app.StopPreviewButton.Text = 'Stop Preview';
            app.NoiseLevelEditField_2Label = uilabel(app.DHMGUIV22UIFigure);
            app.NoiseLevelEditField_2Label.Text = 'Noise Level';
            app.NoiseLevelEditField_2 = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.NoiseLevelEditField_2.Limits = [0 100];
            app.NoiseLevelEditField_2.Value = 30;
            app.RunCombineButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunCombineButton.ButtonPushedFcn = createCallbackFcn(app, @RunCombineButtonPushed, true);
            app.RunCombineButton.Text = 'Run Combine';
            app.SaveFolderEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.SaveFolderEditField.Value = 'image_folder';
            app.SaveFolderEditField_2Label = uilabel(app.DHMGUIV22UIFigure);
            app.SaveFolderEditField_2Label.Text = 'Save Folder';
            app.SaveFolderEditField_2 = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.SaveFolderEditField_2.Value = 'figure_folder';
            app.CombinedSaveFolderEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.CombinedSaveFolderEditFieldLabel.Text = 'Combined Save Folder';
            app.CombinedSaveFolderEditField = uieditfield(app.DHMGUIV22UIFigure, 'text');
            app.CombinedSaveFolderEditField.Value = 'combined_folder';
            app.UIAxes1 = uiaxes(app.DHMGUIV22UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes1.ZTick = [];
            app.PreviewButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SelectImageButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImagePathTextArea = uitextarea(app.DHMGUIV22UIFigure);
            app.ImagePathTextArea.Editable = 'off';
            app.SelectVideoButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.ImagePathTextArea_2 = uitextarea(app.DHMGUIV22UIFigure);
            app.ImagePathTextArea_2.Editable = 'off';
            app.ImageFolderButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.ImagePathTextArea_3 = uitextarea(app.DHMGUIV22UIFigure);
            app.ImagePathTextArea_3.Editable = 'off';
            app.CommonDirectoryButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.CommonDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CommonDirectoryButtonPushed, true);
            app.CommonDirectoryButton.Text = {'Common'; 'Directory'};
            app.ImagePathTextArea_4 = uitextarea(app.DHMGUIV22UIFigure);
            app.ImagePathTextArea_4.Editable = 'off';
            app.SelectROIButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.CalculationParametersLabel = uilabel(app.DHMGUIV22UIFigure);
            app.CalculationParametersLabel.Text = 'Calculation Parameters';
            app.WavelengthnmEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.WavelengthnmEditField.Limits = [1 Inf];
            app.WavelengthnmEditField.Value = 514;
            app.RefracIndexDiffEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.RefracIndexDiffEditFieldLabel.Text = 'Refrac. Index Diff.';
            app.RefracIndexDiffEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.RefracIndexDiffEditField.Limits = [0 1];
            app.RefracIndexDiffEditField.Value = 0.06;
            app.UpperLimitEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Value = 20;
            app.InvertZaxisCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.InvertZaxisCheckBox.Text = 'Invert Z-axis';
            app.SaveHeightCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.SaveHeightCheckBox.Text = 'Save Height';
            app.SaveHeightCheckBox.Value = true;
            app.SaveVolumeCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.SaveVolumeCheckBox.Text = 'Save Volume';
            app.SaveVolumeCheckBox.Value = true;
            app.LowerLimitCheckBox = uicheckbox(app.DHMGUIV22UIFigure);
            app.LowerLimitCheckBox.Text = 'Lower Limit';
            app.LowerLimitCheckBox.Value = true;
            app.InvertZaxisCheckBox_2 = uicheckbox(app.DHMGUIV22UIFigure);
            app.InvertZaxisCheckBox_2.Text = 'Invert Z-axis';
            app.LowerLimitCheckBox_2 = uicheckbox(app.DHMGUIV22UIFigure);
            app.LowerLimitCheckBox_2.Text = 'Lower Limit';
            app.LowerLimitCheckBox_2.Value = true;
            app.UpperLimitEditField_2Label = uilabel(app.DHMGUIV22UIFigure);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.UpperLimitEditField_2.Limits = [0 Inf];
            app.UpperLimitEditField_2.Value = 20;
            app.PixelSizemEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (μm)';
            app.PixelSizemEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.PixelSizemEditField.Limits = [0 Inf];
            app.PixelSizemEditField.Value = 0.5;
            app.ApplyChangesButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ExposuresEditField_2Label = uilabel(app.DHMGUIV22UIFigure);
            app.ExposuresEditField_2Label.Text = 'Exposure (μs)';
            app.ExposuresEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.ExposuresEditField.Limits = [12 15003];
            app.ExposuresEditField.ValueDisplayFormat = '%.0f';
            app.ExposuresEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuresEditFieldValueChanged, true);
            app.ExposuresEditField.Value = 500;
            app.CameraModeDropDownLabel = uilabel(app.DHMGUIV22UIFigure);
            app.CameraModeDropDownLabel.Text = 'Camera Mode';
            app.CameraModeDropDown = uidropdown(app.DHMGUIV22UIFigure);
            app.CameraModeDropDown.Items = {'Mono16', 'Mono8', 'Mono10Packed', 'Mono12Packed'};
            app.CameraModeDropDown.Value = 'Mono16';
            app.FramerateLiveEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FramerateLiveEditFieldLabel.Text = 'Framerate (Live)';
            app.FramerateLiveEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FramerateLiveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateLiveEditField.Editable = 'off';
            app.FramerateSaveEditFieldLabel = uilabel(app.DHMGUIV22UIFigure);
            app.FramerateSaveEditFieldLabel.Text = 'Framerate (Save)';
            app.FramerateSaveEditField = uieditfield(app.DHMGUIV22UIFigure, 'numeric');
            app.FramerateSaveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateSaveEditField.Editable = 'off';
            app.ROISelectButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ROISelectButton.ButtonPushedFcn = createCallbackFcn(app, @ROISelectButtonPushed, true);
            app.ROISelectButton.Text = 'ROI Select';
            app.ROIResetButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ROIResetButton.ButtonPushedFcn = createCallbackFcn(app, @ROIResetButtonPushed, true);
            app.ROIResetButton.Text = 'ROI Reset';
            app.ShowFFTButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.ShowFFTButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFFTButtonPushed, true);
            app.ShowFFTButton.Text = 'Show FFT';
            app.CloseFFTButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.CloseFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFFTButtonPushed, true);
            app.CloseFFTButton.Text = 'Close FFT';
            app.LiveReconButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.LiveReconButton.ButtonPushedFcn = createCallbackFcn(app, @LiveReconButtonPushed, true);
            app.LiveReconButton.Text = 'Live Recon';
            app.CloseReconButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.CloseReconButton.ButtonPushedFcn = createCallbackFcn(app, @CloseReconButtonPushed, true);
            app.CloseReconButton.Text = 'Close Recon';
            app.UIAxes2 = uiaxes(app.DHMGUIV22UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.RunBulkManualButton = uibutton(app.DHMGUIV22UIFigure, 'push');
            app.RunBulkManualButton.ButtonPushedFcn = createCallbackFcn(app, @RunBulkManualButtonPushed, true);
            app.RunBulkManualButton.Text = 'Run Bulk (Manual)';
            app.UIAxes3 = uiaxes(app.DHMGUIV22UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            % Show the figure after all components are created
            app.DHMGUIV22UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_ui_new_naming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHMGUIV22UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHMGUIV22UIFigure)
        end
    end
end